classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CurrentAngleofAttackGauge       matlab.ui.control.SemicircularGauge
        CurrentAngleofAttackGaugeLabel  matlab.ui.control.Label
        TargetAoAKnob                   matlab.ui.control.Knob
        TargetAoAKnobLabel              matlab.ui.control.Label
        TargetAoAEditField              matlab.ui.control.NumericEditField
        TargetAoAEditFieldLabel         matlab.ui.control.Label
        WindSpeedGauge                  matlab.ui.control.Gauge
        WindSpeedGaugeLabel             matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        HardwarePanel                   matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        Refresh                         matlab.ui.control.Button
        PortLabel                       matlab.ui.control.Label
        PortList                        matlab.ui.control.DropDown
        ArduinoConnectionLampLabel      matlab.ui.control.Label
        ArduinoConnectionLamp           matlab.ui.control.Lamp
        BCon                            matlab.ui.control.Button
        BDiscon                         matlab.ui.control.Button
        ArtificialHorizon               Aero.ui.control.ArtificialHorizon
        WindSpeedUnitsButtonGroup       matlab.ui.container.ButtonGroup
        KnotsButton                     matlab.ui.control.RadioButton
        msButton                        matlab.ui.control.RadioButton
        mphButton                       matlab.ui.control.RadioButton
        ftsButton                       matlab.ui.control.RadioButton
        AoAWarningLampLabel             matlab.ui.control.Label
        AoAWarningLamp                  matlab.ui.control.Lamp
        SwitchLabel                     matlab.ui.control.Label
        Switch                          matlab.ui.control.ToggleSwitch
        UIAxes2                         matlab.ui.control.UIAxes
    end

    properties (Access = private)
        READ_TIME_STEP = 0.002
        WRITE_TIME_STEP = 2
        s % serial port
        t % timer (for update GUI and send data)
        t2
        GREEN_COLOR = [0 1 0]
        RED_COLOR = [1 0 0]
        servo_command_value
        data
        startTime
        control_on
        last_command_value
%         athena = animatedline('Color','r','LineWidth',3);
%         minerva = animatedline('Color','g','LineWidth',3);
%         ares = animatedline('Color','b','LineWidth',3);
%         mars = animatedline('Color','c','LineWidth',3);
%         jupiter = animatedline('Color','m','LineWidth',3);
%         neptune = animatedline('Color','y','LineWidth',3);
%         pluto = animatedline('Color','k','LineWidth',3);
    end
    
    methods (Access = private)
        function updateGUI(app, led_state)
            % plot color value in GUI
            app.color_plot.YData = [app.color_plot.YData(2:end) led_state];
            % change color of GUI lamp
            app.LEDLamp.Color = [0 led_state 0]; % scale color values to 0..1 range
        end
        function sendData(app, servo_control)
            % if output buffer is empty write values to serial port in asyncronous mode
            temp=int16(servo_control);
                if(temp>0&&temp<1023)
                    writeline(app.s, num2str(temp)); % send prepared color values over serial to Arduino
                end
        end
        function readData(app)
            % if output buffer is empty write values to serial port in asyncronous mode
            if app.s.NumBytesAvailable~=0
                temp=str2num(readline(app.s));
                disp(temp);
            end
        end
        function readTimerFunction(app, ~, ~)
            % Timer callback
            %             app.updateGUI(0.5);
            app.readData()
        end
        function writeTimerFunction(app, ~, ~)
            if app.control_on=="True"
            disp("attempting command")
            if app.last_command_value~=app.servo_command_value
            disp("different command")
            app.sendData(app.servo_command_value);
            app.last_command_value=app.servo_command_value;
            flush(app.s)
            end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % define time vector for plotting
            %            time = (0 : app.TIME_STEP : 10)';
            app.startTime=datetime("now");
            %             % init and set up axes
            %             app.Axes.Toolbar.Visible = 'off';
            %             disableDefaultInteractivity(app.Axes);
            %
            %             % define color order fot plotting
            %             app.Axes.ColorOrder = app.GREEN_COLOR;
            %
            %             app.color_plot = plot(app.Axes, time, zeros(length(time), 1), 'LineWidth', 2);
            
            % set up timer
            app.t = timer;
            app.t.TimerFcn = @app.readTimerFunction;
            app.t.ExecutionMode = 'fixedRate';
            app.t.Period = app.READ_TIME_STEP;
            app.t.BusyMode = 'drop';
            app.t2 = timer;
            app.t2.TimerFcn = @app.writeTimerFunction;
            app.t2.ExecutionMode = 'fixedRate';
            app.t2.Period = app.WRITE_TIME_STEP;
            app.t2.BusyMode = 'drop';
            % Initial Settings
            app.servo_command_value=app.TargetAoAEditField.Value;
            app.last_command_value=app.servo_command_value;
            app.control_on=app.Switch.Value;
            % looking for available serial ports
            % (Instrument Control Toolbox required)
            if license('test', 'instr_control_toolbox')
                p = serialportlist();
                app.PortList.Items = p;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.BDisconButtonPushed();
            delete(app)
        end

        % Button pushed function: Refresh
        function RefreshButtonPushed(app, event)
            if license('test', 'instr_control_toolbox')
                p = serialportlist();
                app.PortList.Items = p;
            end
        end

        % Button pushed function: BCon
        function BConButtonPushed(app, event)
            % on connect open serial port, start timer and change lamp color to green
            if app.t.running == "off"
                app.s = serialport(app.PortList.Value,115200);
                configureTerminator(app.s,"CR")
                start(app.t);
                start(app.t2)
                app.ArduinoConnectionLamp.Color = app.GREEN_COLOR;
            end
        end

        % Button pushed function: BDiscon
        function BDisconButtonPushed(app, event)
            % on disconnect stop timer, close serial port and change lamp color to red
            if app.t.running == "on"
                stop(app.t);
                stop(app.t2);
                %                stopasync(app.s)
                delete(app.s);
            end
            app.ArduinoConnectionLamp.Color = app.RED_COLOR;
        end

        % Value changed function: TargetAoAEditField
        function TargetAoAEditFieldValueChanged(app, event)
            value=app.TargetAoAEditField.Value;
            app.servo_command_value = value;
            app.TargetAoAKnob.Value= value;
        end

        % Value changed function: TargetAoAKnob
        function TargetAoAKnobValueChanged(app, event)
            value = app.TargetAoAKnob.Value;
            app.servo_command_value=value;
            app.TargetAoAEditField.Value=value;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.control_on = app.Switch.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CurrentAngleofAttackGauge = uigauge(app.UIFigure, 'semicircular');
            app.CurrentAngleofAttackGauge.Limits = [-90 90];
            app.CurrentAngleofAttackGaugeLabel = uilabel(app.UIFigure);
            app.CurrentAngleofAttackGaugeLabel.Text = 'Current Angle of Attack';
            app.TargetAoAKnob = uiknob(app.UIFigure, 'continuous');
            app.TargetAoAKnob.Limits = [1 1022];
            app.TargetAoAKnob.ValueChangedFcn = createCallbackFcn(app, @TargetAoAKnobValueChanged, true);
            app.TargetAoAKnob.Value = 1;
            app.TargetAoAKnobLabel = uilabel(app.UIFigure);
            app.TargetAoAKnobLabel.Text = 'Target AoA';
            app.TargetAoAEditField = uieditfield(app.UIFigure, 'numeric');
            app.TargetAoAEditField.Limits = [1 1022];
            app.TargetAoAEditField.RoundFractionalValues = 'on';
            app.TargetAoAEditField.ValueDisplayFormat = '%.0f';
            app.TargetAoAEditField.ValueChangedFcn = createCallbackFcn(app, @TargetAoAEditFieldValueChanged, true);
            app.TargetAoAEditField.Value = 1;
            app.TargetAoAEditFieldLabel = uilabel(app.UIFigure);
            app.TargetAoAEditFieldLabel.Text = 'Target AoA';
            app.WindSpeedGauge = uigauge(app.UIFigure, 'circular');
            app.WindSpeedGauge.Limits = [0 35];
            app.WindSpeedGaugeLabel = uilabel(app.UIFigure);
            app.WindSpeedGaugeLabel.Text = 'Wind Speed';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.HardwarePanel = uipanel(app.Panel);
            app.HardwarePanel.Title = 'Hardware';
            app.GridLayout11 = uigridlayout(app.HardwarePanel);
            app.Refresh = uibutton(app.GridLayout11, 'push');
            app.Refresh.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.Refresh.Text = 'Refresh';
            app.PortLabel = uilabel(app.GridLayout11);
            app.PortLabel.Text = 'Port';
            app.PortList = uidropdown(app.GridLayout11);
            app.PortList.Items = {'COM?'};
            app.PortList.Editable = 'on';
            app.PortList.Value = 'COM?';
            app.ArduinoConnectionLampLabel = uilabel(app.Panel);
            app.ArduinoConnectionLampLabel.Text = 'Arduino Connection';
            app.ArduinoConnectionLamp = uilamp(app.Panel);
            app.BCon = uibutton(app.Panel, 'push');
            app.BCon.ButtonPushedFcn = createCallbackFcn(app, @BConButtonPushed, true);
            app.BCon.Text = 'Connect';
            app.BDiscon = uibutton(app.Panel, 'push');
            app.BDiscon.ButtonPushedFcn = createCallbackFcn(app, @BDisconButtonPushed, true);
            app.BDiscon.Text = 'Disconnect';
            app.ArtificialHorizon = uiaerohorizon(app.UIFigure);
            app.WindSpeedUnitsButtonGroup = uibuttongroup(app.UIFigure);
            app.WindSpeedUnitsButtonGroup.Title = 'Wind Speed Units';
            app.KnotsButton = uiradiobutton(app.WindSpeedUnitsButtonGroup);
            app.KnotsButton.Text = 'Knots';
            app.KnotsButton.Value = true;
            app.msButton = uiradiobutton(app.WindSpeedUnitsButtonGroup);
            app.msButton.Text = 'm/s';
            app.mphButton = uiradiobutton(app.WindSpeedUnitsButtonGroup);
            app.mphButton.Text = 'mph';
            app.ftsButton = uiradiobutton(app.WindSpeedUnitsButtonGroup);
            app.ftsButton.Text = 'ft/s';
            app.AoAWarningLampLabel = uilabel(app.UIFigure);
            app.AoAWarningLampLabel.Text = 'AoA Warning';
            app.AoAWarningLamp = uilamp(app.UIFigure);
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.Items = {'False', 'True'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'False';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Center Lift Sensor')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Force (N)')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end